classdef klasyfikator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WczytywanieobrazwPanel         matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        WczytajObraziKlasyfikujButton  matlab.ui.control.Button
        Nazwa                          matlab.ui.control.Label
        WynikiklasyfikacjiPanel        matlab.ui.container.Panel
        Image_2                        matlab.ui.control.Image
        Skl                            matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        WywietlwicejinformacjiButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajObraziKlasyfikujButton
        function WczytajObraziKlasyfikujButtonPushed(app, event)
            
            
            file = uigetfile('*.jpg','Select One');
            app.Image.ImageSource = file;
            
            app.Nazwa.Text = file;
 try           
            GNnet = load('trainedGN');
            wytrenowana = GNnet.trainedGN;
            
            im = imread(file);
            size(im);

            [YPred,scores] = classify(wytrenowana,im);

            
            classNamesTop = wytrenowana.Layers(end).ClassNames;
            

            disp(['Klasyfikacja:',YPred])

            
            h = figure;
            barh(scores)
            xlim([0 1])
            title('Predictions')
            xlabel('Probability')
            yticklabels(classNamesTop)
            saveas(h,'obraz','jpg')
            close(h)
            
            app.Image_2.ImageSource = imread('obraz.jpg');
            app.Label_2.Text = YPred;
catch
    msgbox('Wystąpił błąd, wybierz właściwy obraz!','Error','error')
end      
            
        end

        % Callback function
        function KlasyfikujButtonPushed(app, event)
            
        end

        % Button pushed function: WywietlwicejinformacjiButton
        function WywietlwicejinformacjiButtonPushed(app, event)
            if(app.Label_2.Text == "ARR")
               msgbox("Stan, w którym skurcze mięśnia sercowego są " + ...
                   "nieregularne, a ich częstotliwość wychodzi poza bezpieczny zakres " + ...
                   "60–100 uderzeń na minutę. Stan taki często stanowić może " + ...
                   "zagrożenie dla życia, chociaż nie jest to regułą.","Arytmia",'help');
            elseif(app.Label_2.Text == "CHF")
                msgbox("Postępujący zespół objawów wynikających ze zmniejszenia pojemności " + ...
                    "minutowej serca, któremu towarzyszą obiektywne dowody dysfunkcji mięśnia sercowego.","Przewlekła niewydolność serca",'help');
            else
                msgbox("Prawidłowy rytm bicia serca.","Wynik zgodny z normą",'help');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WczytywanieobrazwPanel = uipanel(app.UIFigure);
            app.WczytywanieobrazwPanel.Title = 'Wczytywanie obrazów';
            app.Image = uiimage(app.WczytywanieobrazwPanel);
            app.Image.ImageSource = 'ikonka.png';
            app.WczytajObraziKlasyfikujButton = uibutton(app.WczytywanieobrazwPanel, 'push');
            app.WczytajObraziKlasyfikujButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajObraziKlasyfikujButtonPushed, true);
            app.WczytajObraziKlasyfikujButton.Icon = '16178026751555936518-128.png';
            app.WczytajObraziKlasyfikujButton.Text = 'Wczytaj Obraz i Klasyfikuj ';
            app.Nazwa = uilabel(app.WczytywanieobrazwPanel);
            app.Nazwa.Text = 'Nazwa Pliku';
            app.WynikiklasyfikacjiPanel = uipanel(app.UIFigure);
            app.WynikiklasyfikacjiPanel.Title = 'Wyniki klasyfikacji';
            app.Image_2 = uiimage(app.WynikiklasyfikacjiPanel);
            app.Image_2.ImageSource = 'ikonka2.png';
            app.Skl = uilabel(app.WynikiklasyfikacjiPanel);
            app.Skl.Text = 'Sklasyfikowano jako:';
            app.Label_2 = uilabel(app.WynikiklasyfikacjiPanel);
            app.Label_2.Text = '';
            app.WywietlwicejinformacjiButton = uibutton(app.WynikiklasyfikacjiPanel, 'push');
            app.WywietlwicejinformacjiButton.ButtonPushedFcn = createCallbackFcn(app, @WywietlwicejinformacjiButtonPushed, true);
            app.WywietlwicejinformacjiButton.Text = 'Wyświetl więcej informacji';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = klasyfikator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end